classdef IonicPolymerGloveGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DisplayTab                      matlab.ui.container.Tab
        BluetoothDongleCOMPortEditFieldLabel  matlab.ui.control.Label
        BluetoothDongleCOMPortEditField  matlab.ui.control.EditField
        ConnectButton                   matlab.ui.control.Button
        DisconnectButton                matlab.ui.control.Button
        HandPlot                        matlab.ui.control.UIAxes
        ReferenceCapacitanceDataButton  matlab.ui.control.Button
        PlotTab                         matlab.ui.container.Tab
        CapacitanceAxes                 matlab.ui.control.UIAxes
        GyroAxes                        matlab.ui.control.UIAxes
        AccelerationAxes                matlab.ui.control.UIAxes
        ReferenceSensorTab              matlab.ui.container.Tab
        ConnectRefButton                matlab.ui.control.Button
        RefSensorAxes                   matlab.ui.control.UIAxes
        DisconnectRefButton             matlab.ui.control.Button
        ZeroButton                      matlab.ui.control.Button
        ReadingLampLabel                matlab.ui.control.Label
        ReadingLamp                     matlab.ui.control.Lamp
        NotReadingLamp                  matlab.ui.control.Lamp
        CurrentValueEditFieldLabel      matlab.ui.control.Label
        CurrentValueEditField           matlab.ui.control.NumericEditField
        CalibrateTab                    matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        UnitSwitch                      matlab.ui.control.Switch
    end


    properties (Access = public)
        % Serial Variables
        serialCOM
        
        % Timer callback variable
        dataCallbackTimer
        refSensorTimer
        
        % Data Variables
        timeData = nan;
        cap1Data = nan;
        cap2Data = nan;
        cap3Data = nan;
        cap4Data = nan;
        cap5Data = nan;
        cap6Data = nan;
        cap7Data = nan;
        cap8Data = nan;
        cap9Data = nan;
        cap10Data = nan;
        cap11Data = nan;
        cap12Data = nan;
        cap13Data = nan;
        cap14Data = nan;
        cap15Data = nan;
        cap16Data = nan;
        accelXData = nan;
        accelYData = nan;
        accelZData = nan;
        gyroXData = nan;
        gyroYData = nan;
        gyroZData = nan;
        
        % Adjusted Variables
        timeAdjData = nan;
        cap1AdjData = nan;
        cap2AdjData = nan;
        cap3AdjData = nan;
        cap4AdjData = nan;
        cap5AdjData = nan;
        cap6AdjData = nan;
        cap7AdjData = nan;
        cap8AdjData = nan;
        cap9AdjData = nan;
        cap10AdjData = nan;
        cap11AdjData = nan;
        cap12AdjData = nan;
        cap13AdjData = nan;
        cap14AdjData = nan;
        cap15AdjData = nan;
        cap16AdjData = nan;
        accelXAdjData = nan;
        accelYAdjData = nan;
        accelZAdjData = nan;
        gyroXAdjData = nan;
        gyroYAdjData = nan;
        gyroZAdjData = nan;
        
        % Capcitance data References
        capRef = zeros(1,16);
        
        % Variables for plotting 
        plotLength = 10; % seconds
        
        % Variables for hand plot
        colourMap
        handBackground
        sensorMasks
        
        % Recording Data
        date = datetime('today');
        
        % Reference Sensor Variables
        BufferSize = 250;
        
        nReady = nan;
        
        totalData = nan;
        totalTime = nan;
        
        plotData = nan;
    end

    methods (Access = public)

        
        function plotHand(app, sensorValues)
            sensorValues = round( sensorValues * 63 + 1 );

            displayHand = app.handBackground;
            
            for count = 1:16
                displayHand(:,:,1) = displayHand(:,:,1) + app.sensorMasks(:,:,1,count)*app.colourMap(sensorValues(count), 1);
                displayHand(:,:,2) = displayHand(:,:,2) + app.sensorMasks(:,:,2,count)*app.colourMap(sensorValues(count), 2);
                displayHand(:,:,3) = displayHand(:,:,3) + app.sensorMasks(:,:,3,count)*app.colourMap(sensorValues(count), 3);
            end
            
            image(app.HandPlot, displayHand);
            
        end
        
    end

    methods (Access = private)
    
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Adjust the hand plot properties
            %app.HandPlot.XColor = 'none';
            %app.HandPlot.YColor = 'none';
            
            % Initialize variables for the hand plot
            
            app.colourMap = [0,         0,  143.4375; ...
                         0,         0,  159.3750; ...
                         0,         0,  175.3125; ...
                         0,         0,  191.2500; ...
                         0,         0,  207.1875; ...
                         0,         0,  223.1250; ...
                         0,         0,  239.0625; ...
                         0,         0,  255.0000; ...
                         0,   15.9375,  255.0000; ...
                         0,   31.8750,  255.0000; ...
                         0,   47.8125,  255.0000; ...
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                  127.5000,         0,         0; ];
        
            app.handBackground = imread('Hand Images/hand.png');
         
            app.sensorMasks = uint8(zeros(size(app.handBackground,1), size(app.handBackground,2), 3, 16));
    
            app.sensorMasks(:,:,:,1) = imread('Hand Images/I1_mask.png');
            app.sensorMasks(:,:,:,2) = imread('Hand Images/I2_mask.png');
            app.sensorMasks(:,:,:,3) = imread('Hand Images/I3_mask.png');
            app.sensorMasks(:,:,:,4) = imread('Hand Images/M1_mask.png');
            app.sensorMasks(:,:,:,5) = imread('Hand Images/M3_mask.png');
            app.sensorMasks(:,:,:,6) = imread('Hand Images/R1_mask.png');
            app.sensorMasks(:,:,:,7) = imread('Hand Images/R3_mask.png');
            app.sensorMasks(:,:,:,8) = imread('Hand Images/S1_mask.png');
            app.sensorMasks(:,:,:,9) = imread('Hand Images/S3_mask.png');
            app.sensorMasks(:,:,:,10) = imread('Hand Images/T1_mask.png');
            app.sensorMasks(:,:,:,11) = imread('Hand Images/T2_mask.png');
            app.sensorMasks(:,:,:,12) = imread('Hand Images/P1_mask.png');
            app.sensorMasks(:,:,:,13) = imread('Hand Images/P2_mask.png');
            app.sensorMasks(:,:,:,14) = imread('Hand Images/P3_mask.png');
            app.sensorMasks(:,:,:,15) = imread('Hand Images/P4_mask.png');
            app.sensorMasks(:,:,:,16) = imread('Hand Images/P5_mask.png');
            
            for count = 1:16 
                app.handBackground(:,:,:) = app.handBackground(:,:,:) - app.sensorMasks(:,:,:,count);    
            end
    
            app.sensorMasks = app.sensorMasks/255;
            
            sValue = linspace(0,1,16);
            
            plotHand(app, sValue);
        
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.BluetoothDongleCOMPortEditField.Enable = 'off';
            app.ConnectButton.Enable = 'off';
            app.DisconnectButton.Enable = 'on';
            
            % Initalize the data variables
            app.timeData = nan;
            app.cap1Data = nan;
            app.cap2Data = nan;
            app.cap3Data = nan;
            app.cap4Data = nan;
            app.cap5Data = nan;
            app.cap6Data = nan;
            app.cap7Data = nan;
            app.cap8Data = nan;
            app.cap9Data = nan;
            app.cap10Data = nan;
            app.cap11Data = nan;
            app.cap12Data = nan;
            app.cap13Data = nan;
            app.cap14Data = nan;
            app.cap15Data = nan;
            app.cap16Data = nan;
            app.accelXData = nan;
            app.accelYData = nan;
            app.accelZData = nan;
            app.gyroXData = nan;
            app.gyroYData = nan;
            app.gyroZData = nan;
            
            % Adjusted Variables
            app.timeAdjData = nan;
            app.cap1AdjData = nan;
            app.cap2AdjData = nan;
            app.cap3AdjData = nan;
            app.cap4AdjData = nan;
            app.cap5AdjData = nan;
            app.cap6AdjData = nan;
            app.cap7AdjData = nan;
            app.cap8AdjData = nan;
            app.cap9AdjData = nan;
            app.cap10AdjData = nan;
            app.cap11AdjData = nan;
            app.cap12AdjData = nan;
            app.cap13AdjData = nan;
            app.cap14AdjData = nan;
            app.cap15AdjData = nan;
            app.cap16AdjData = nan;
            app.accelXAdjData = nan;
            app.accelYAdjData = nan;
            app.accelZAdjData = nan;
            app.gyroXAdjData = nan;
            app.gyroYAdjData = nan;
            app.gyroZAdjData = nan;
            
            app.capRef = zeros(1,16);
            
            % Open the serial port with the bluetooth to serial to USB board
            disp(['Connecting to bluetooth dongle on :' app.BluetoothDongleCOMPortEditField.Value]);
                        
            % First close any current serial channels open in MATLAB
            com_objs = instrfindall; %Note: "instrfindall" will display all pertinent information about any open serial port
            if ~isempty(com_objs)
                fclose(com_objs); %close this serial COM port connection, but leave the serial object in existence
                delete(com_objs); %delete the serial object too
            end
        
            app.serialCOM = serial(app.BluetoothDongleCOMPortEditField.Value); % create a serial port object 
        
            % set serial parameters
            set(app.serialCOM, 'DataBits', 8);
            set(app.serialCOM, 'StopBits', 1);
            set(app.serialCOM, 'BaudRate', 115200);
            set(app.serialCOM, 'Parity', 'none');
            
            % Setup the serial port to generate an interrrupt when data is avaliable
            app.serialCOM.Terminator = 'z'; 
            app.serialCOM.BytesAvailableFcnMode = 'terminator';
            app.serialCOM.BytesAvailableFcn = {@dataCallback, app};
            
            fopen(app.serialCOM); % connect the serial port
            
            disp('If there were no errors then the connection was successful!');
            
            % Set up a periodic callback for plotting 
            app.dataCallbackTimer = timer( 'Period', 0.2,'TimerFcn', {@plotData, app}, 'ExecutionMode', 'fixedRate', 'BusyMode', 'queue');
            start(app.dataCallbackTimer)
            
            % start a stopwatch 
            tic;
            
        end

        % Button pushed function: ConnectRefButton
        function ConnectRefButtonPushed(app, event)
            app.ConnectRefButton.Enable = 'off';
            app.DisconnectRefButton.Enable = 'on';
            app.ZeroButton.Enable = 'on';
            app.UnitSwitch.Enable = 'off';
            
            loadRefLibrary();
            
            app.totalData = nan;
            app.totalTime = nan;
            
            ReadInterval = 100;    %in ms
            
            app.refSensorTimer = timer( 'Period', (ReadInterval/1000),'TimerFcn', {@plotRefData, app}, 'ExecutionMode', 'fixedRate', 'BusyMode', 'queue');
            start(app.refSensorTimer)
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.BluetoothDongleCOMPortEditField.Enable = 'on';
            app.ConnectButton.Enable = 'on';
            app.DisconnectButton.Enable = 'off';
            
            fclose(app.serialCOM);
            delete(app.serialCOM);
            
            disp('The COM Port has been closed');
            
            stop(app.dataCallbackTimer)
            
        end

        % Button pushed function: DisconnectRefButton
        function DisconnectRefButtonPushed(app, event)
            stop(app.refSensorTimer)
                        
            app.ConnectRefButton.Enable = 'on';
            app.DisconnectRefButton.Enable = 'off';
            app.ZeroButton.Enable = 'off';
            app.ReadingLamp.Enable = 'off';
            app.NotReadingLamp.Enable = 'on';
            app.UnitSwitch.Enable = 'on';
            
            calllib('PPSRef','ppsStop');
            
            if libisloaded('PPSRef')
               unloadlibrary('PPSRef') 
            end
        end

        % Button pushed function: ReferenceCapacitanceDataButton
        function ReferenceCapacitanceDataButtonPushed(app, event)
             app.capRef(1) = mean(app.cap1Data(end-10:end));
             app.capRef(2) = mean(app.cap2Data(end-10:end));
             app.capRef(3) = mean(app.cap3Data(end-10:end));
             app.capRef(4) = mean(app.cap4Data(end-10:end));
             app.capRef(5) = mean(app.cap5Data(end-10:end));
             app.capRef(6) = mean(app.cap6Data(end-10:end));
             app.capRef(7) = mean(app.cap7Data(end-10:end));
             app.capRef(8) = mean(app.cap8Data(end-10:end));
             app.capRef(9) = mean(app.cap9Data(end-10:end));
             app.capRef(10) = mean(app.cap10Data(end-10:end));
             app.capRef(11) = mean(app.cap11Data(end-10:end));
             app.capRef(12) = mean(app.cap12Data(end-10:end));
             app.capRef(13) = mean(app.cap13Data(end-10:end));
             app.capRef(14) = mean(app.cap14Data(end-10:end));
             app.capRef(15) = mean(app.cap15Data(end-10:end));
             app.capRef(16) = mean(app.cap16Data(end-10:end));
        end

        % Callback function
        function SaveButtonPushed(app, event)
            % Make all the variables local
            dateRecord = app.date;
            timeDataRecord = app.timeData;
            measVoltageDataRecord = app.measVoltageData;
            measCurrentDataRecord = app.measCurrentData;
            measResistanceDataRecord = app.measResistanceData;
            
            
            % Save the Data to a Matlab File
            save([char(app.FileNameEditField.Value) '.mat'], ...
                'dateRecord',...
                'timeDataRecord',...
                'measVoltageDataRecord',...
                'measCurrentDataRecord',...
                'measResistanceDataRecord');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
            
            if libisloaded('PPSRef')
               calllib('PPSRef','ppsStop');
               unloadlibrary('PPSRef') 
            end
        end

        % Button pushed function: ZeroButton
        function ZeroButtonPushed(app, event)
            calllib('PPSRef','ppsSetBaseline');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DisplayTab = uitab(app.TabGroup);
            app.DisplayTab.Title = 'Display';
            app.BluetoothDongleCOMPortEditFieldLabel = uilabel(app.DisplayTab);
            app.BluetoothDongleCOMPortEditFieldLabel.Text = 'Bluetooth Dongle COM Port:';
            app.BluetoothDongleCOMPortEditField = uieditfield(app.DisplayTab, 'text');
            app.BluetoothDongleCOMPortEditField.Value = 'COM25';
            app.ConnectButton = uibutton(app.DisplayTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.DisplayTab, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.HandPlot = uiaxes(app.DisplayTab);
            app.HandPlot.XTick = [];
            app.HandPlot.YTick = [];
            app.ReferenceCapacitanceDataButton = uibutton(app.DisplayTab, 'push');
            app.ReferenceCapacitanceDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReferenceCapacitanceDataButtonPushed, true);
            app.ReferenceCapacitanceDataButton.Text = 'Reference Capacitance Data';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.CapacitanceAxes = uiaxes(app.PlotTab);
            xlabel(app.CapacitanceAxes, 'Time [s]')
            ylabel(app.CapacitanceAxes, 'Capacitance')
            app.GyroAxes = uiaxes(app.PlotTab);
            xlabel(app.GyroAxes, 'Time [s]')
            ylabel(app.GyroAxes, 'Gyro')
            app.AccelerationAxes = uiaxes(app.PlotTab);
            xlabel(app.AccelerationAxes, 'Time [s]')
            ylabel(app.AccelerationAxes, 'Acceleration')
            app.ReferenceSensorTab = uitab(app.TabGroup);
            app.ReferenceSensorTab.Title = 'Reference Sensor';
            app.ConnectRefButton = uibutton(app.ReferenceSensorTab, 'push');
            app.ConnectRefButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectRefButtonPushed, true);
            app.ConnectRefButton.Text = 'Connect Ref.';
            app.RefSensorAxes = uiaxes(app.ReferenceSensorTab);
            title(app.RefSensorAxes, 'Reference Sensor')
            xlabel(app.RefSensorAxes, 'Time (ms)')
            ylabel(app.RefSensorAxes, 'Force (lbf)')
            app.DisconnectRefButton = uibutton(app.ReferenceSensorTab, 'push');
            app.DisconnectRefButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectRefButtonPushed, true);
            app.DisconnectRefButton.Enable = 'off';
            app.DisconnectRefButton.Text = 'Disconnect Ref.';
            app.ZeroButton = uibutton(app.ReferenceSensorTab, 'push');
            app.ZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroButtonPushed, true);
            app.ZeroButton.Enable = 'off';
            app.ZeroButton.Text = 'Zero';
            app.ReadingLampLabel = uilabel(app.ReferenceSensorTab);
            app.ReadingLampLabel.Text = 'Reading';
            app.ReadingLamp = uilamp(app.ReferenceSensorTab);
            app.ReadingLamp.Enable = 'off';
            app.NotReadingLamp = uilamp(app.ReferenceSensorTab);
            app.CurrentValueEditFieldLabel = uilabel(app.ReferenceSensorTab);
            app.CurrentValueEditFieldLabel.Text = 'Current Value';
            app.CurrentValueEditField = uieditfield(app.ReferenceSensorTab, 'numeric');
            app.CurrentValueEditField.Editable = 'off';
            app.CalibrateTab = uitab(app.TabGroup);
            app.CalibrateTab.Title = 'Calibrate';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.UnitSwitch = uiswitch(app.UIFigure, 'slider');
            app.UnitSwitch.Items = {'Pound-Force', 'Newtons    '};
            app.UnitSwitch.Value = 'Pound-Force';
        end
    end

    methods (Access = public)

        % Construct app
        function app = IonicPolymerGloveGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end